classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        LineCodingLabel            matlab.ui.control.Label
        BitRateEditField           matlab.ui.control.NumericEditField
        BitRateEditFieldLabel      matlab.ui.control.Label
        GenerateButton             matlab.ui.control.Button
        DigitalDataEditField       matlab.ui.control.EditField
        DigitalDataEditFieldLabel  matlab.ui.control.Label
        UIAxes10                   matlab.ui.control.UIAxes
        UIAxes9                    matlab.ui.control.UIAxes
        UIAxes8                    matlab.ui.control.UIAxes
        UIAxes7                    matlab.ui.control.UIAxes
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes5                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

       
    methods (Access = private)
        
        function [t n x] = calculate(app, bits, bitrate)
            T = length(bits)/bitrate; 
            n = 300; 
            N = n*length(bits);
            dt = T/N; % Rearrange.
            t = 0:dt:T; % Range
            x = zeros(1,length(t)); % output signal
        end
        function [t, x] = unrz(app, bits, bitrate)
            [t n x] = app.calculate(bits, bitrate)
            for i = 0:length(bits)-1
              if bits(i+1) == 1
                x(i*n+1:(i+1)*n) = 1;
              else
                x(i*n+1:(i+1)*n) = 0;
              end
            end
        end
        function [t, x] = urz(app, bits, bitrate)
            [t n x] = app.calculate(bits, bitrate)
            
            for i = 0:length(bits)-1
              if bits(i+1) == 1
                x(i*n+1:(i+0.5)*n) = 1;
                x((i+0.5)*n+1:(i+1)*n) = 0;
              else
                x(i*n+1:(i+1)*n) = 0;
              end
            end
        end
        function [t, L, I] = pnrz(app, bits, bitrate)
            [t n L] = app.calculate(bits, bitrate)
            I = L;
            
            if bits(1) == 0
                dir = -1;
            else
                dir = 1;
            end

            for i = 0:length(bits)-1
              if bits(i+1) == 1
                  L(i*n+1:(i+1)*n) = 1;
                  dir = -1*dir;
                  I(i*n+1:(i+1)*n) = dir
              else
                  L(i*n+1:(i+1)*n) = -1;
                  I(i*n+1:(i+1)*n) = dir
              end
              
            end
        end
        function [t, x] = prz(app, bits, bitrate)
            
            [t n x] = app.calculate(bits, bitrate)
            
            for i = 0:length(bits)-1
              if bits(i+1) == 1
                x(i*n+1:(i+0.5)*n) = 1;
                x((i+0.5)*n+1:(i+1)*n) = 0;
              else
                x(i*n+1:(i+0.5)*n) = -1; % 1 - 150 -> -1
                x((i+0.5)*n+1:(i+1)*n) = 0; % 151 - 300 -> 0
              end
            end
        end
        function [t, x] = manchester(app, bits, bitrate)
            [t n x] = app.calculate(bits, bitrate)

            for i = 0:length(bits)-1
              if bits(i+1) == 1
                x(i*n+1:(i+0.5)*n) = -1;
                x((i+0.5)*n+1:(i+1)*n) = 1;
              else
                x(i*n+1:(i+0.5)*n) = 1;
                x((i+0.5)*n+1:(i+1)*n) = -1;
              end
            end
        end
        function [t, x] = diffmanchester(app, bits, bitrate)
            [t n x] = app.calculate(bits, bitrate);
            
            if (bits(1) == 1)
                dir = [1 -1]
            else
                dir = [-1 1]
            end
            for i = 0:length(bits)-1
              if bits(i+1) == 1
                x(i*n+1:(i+0.5)*n) = dir(2);
                x((i+0.5)*n+1:(i+1)*n) = dir(1);
                dir = [dir(2) dir(1)]
              else
                x(i*n+1:(i+0.5)*n) = dir(1);
                x((i+0.5)*n+1:(i+1)*n) = dir(2);
              end
            end
        end
        function [t, x] = ami(app, bits, bitrate)
            [t n x] = app.calculate(bits, bitrate);
            
            dir = 1;
            for i = 0:length(bits)-1
              if bits(i+1) == 1
                x(i*n+1:(i+1)*n) = dir;
                dir = dir * -1;
              else
                x(i*n+1:(i+1)*n) = 0;
              end
            end
        end
        function [t, x] = psuedoternary(app, bits, bitrate)
            [t n x] = app.calculate(bits, bitrate);
            
            dir = 1;
            for i = 0:length(bits)-1
              if bits(i+1) == 0
                x(i*n+1:(i+1)*n) = dir;
                dir = dir * -1;
              else
                x(i*n+1:(i+1)*n) = 0;
              end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.press_convert(app);
        end

        % Button pushed function: GenerateButton
        function press_convert(app, event)
            bits_string = app.DigitalDataEditField.Value;
            for i = 1:length(bits_string)
                c = bits_string(i);
                if(c == '0' || c== '1')
                    bits(i) = str2num(c);
                end
            end
            bitrate = app.BitRateEditField.Value; % bits per second

            % Unipolar-NRZ
            [t,s] = app.unrz(bits, bitrate);
            plot(app.UIAxes,t,s,'LineWidth',3, 'Color', "#0D4C92");
            
            
            % Unipolar-RZ
            [t,s] = app.urz(bits, bitrate);
            plot(app.UIAxes10,t,s,'LineWidth',3, 'Color', "#0D4C92");
        
            % Polar NRZ-L and NRZ-I
            [t,s1,s2] = app.pnrz(bits,bitrate)
            plot(app.UIAxes3, t, s1, "LineWidth", 3, 'Color', "#0D4C92");
          

            % NRZ-I
            plot(app.UIAxes4, t, s2, "LineWidth", 3, 'Color', "#0D4C92");
          

            % Polar-RZ
            [t,s] = app.prz(bits, bitrate);
            plot(app.UIAxes5,t,s,'LineWidth',3, 'Color', "#0D4C92");


            % Manchesters
            [t,s] = app.manchester(bits, bitrate);
            plot(app.UIAxes6,t,s,'LineWidth',3, 'Color', "#0D4C92");
           

            % Differenctial Manchesters
            [t,s] = app.diffmanchester(bits, bitrate);
            plot(app.UIAxes7,t,s,'LineWidth',3, 'Color', "#0D4C92");
          
            % AMI
            [t,s] = app.ami(bits, bitrate);
            plot(app.UIAxes8,t,s,'LineWidth',3, 'Color', "#0D4C92");
      

            % Psuedoternary
            [t,s] = app.psuedoternary(bits, bitrate);
            plot(app.UIAxes9,t,s,'LineWidth',3, 'Color', "#0D4C92");
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Unipolar-NRZ')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'V (Amplitude)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Polar-NRZ-L')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'V (Amplitude)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Polar-NRZ-I')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'V (Amplitude)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Polar-RZ')
            xlabel(app.UIAxes5, 'Time')
            ylabel(app.UIAxes5, 'V (Amplitude)')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Manchester')
            xlabel(app.UIAxes6, 'Time')
            ylabel(app.UIAxes6, 'V (Amplitude)')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Differential Manchester')
            xlabel(app.UIAxes7, 'Time')
            ylabel(app.UIAxes7, 'V (Amplitude)')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'AMI')
            xlabel(app.UIAxes8, 'Time')
            ylabel(app.UIAxes8, 'V (Amplitude)')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, 'Psuedoternary')
            xlabel(app.UIAxes9, 'Time')
            ylabel(app.UIAxes9, 'V (Amplitude)')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.UIFigure);
            title(app.UIAxes10, 'Unipolar-RZ')
            xlabel(app.UIAxes10, 'Time')
            ylabel(app.UIAxes10, 'V(Amplitude)')
            zlabel(app.UIAxes10, 'Z')
            colormap(app.UIAxes10, 'colorcube')
            app.DigitalDataEditFieldLabel = uilabel(app.UIFigure);
            app.DigitalDataEditFieldLabel.Text = 'Digital Data';
            app.DigitalDataEditField = uieditfield(app.UIFigure, 'text');
            app.DigitalDataEditField.Value = '010100110';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @press_convert, true);
            app.GenerateButton.Text = 'Generate';
            app.BitRateEditFieldLabel = uilabel(app.UIFigure);
            app.BitRateEditFieldLabel.Text = 'Bit Rate';
            app.BitRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BitRateEditField.Value = 1;
            app.LineCodingLabel = uilabel(app.UIFigure);
            app.LineCodingLabel.Text = 'Line Coding';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end